classdef applicazionebottone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SATURAZIONEO2EditField         matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        StoricoTable                   matlab.ui.control.Table
        AVVIAButton                    matlab.ui.control.Button
        CODICELamp                     matlab.ui.control.Lamp
        CODICELampLabel                matlab.ui.control.Label
        IndicazionidelsistemaTextArea  matlab.ui.control.TextArea
        TextArea2Label                 matlab.ui.control.Label
        SintomienotemedicheTextArea    matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        PAmmHgEditField                matlab.ui.control.NumericEditField
        PAmmHgEditFieldLabel           matlab.ui.control.Label
        FCbpmEditField                 matlab.ui.control.NumericEditField
        FCbpmEditFieldLabel            matlab.ui.control.Label
        ETEditField                    matlab.ui.control.NumericEditField
        ETEditFieldLabel               matlab.ui.control.Label
        NOMEECOGNOMEEditField          matlab.ui.control.EditField
        NOMEECOGNOMEEditFieldLabel     matlab.ui.control.Label
        CODICEFISCALEEditField         matlab.ui.control.EditField
        CODICEFISCALEEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AVVIAButton
        function AVVIAButtonPushed(app, event)
%Recupero dati dall'interfaccia grafica
cf_paziente      = upper(strip(char(app.CODICEFISCALEEditField.Value))); 
sat              = app.SATURAZIONEO2EditField.Value; 
fc               = app.FCbpmEditField.Value;
pa               = app.PAmmHgEditField.Value;    
eta              = app.ETEditField.Value; 
sintomi_correnti = char(app.SintomienotemedicheTextArea.Value); 

if isempty(cf_paziente) || length(cf_paziente) < 16
    app.IndicazionidelsistemaTextArea.Value = "ERRORE: Inserire un Codice Fiscale valido di 16 caratteri.";
    return;
end

file_pazienti = 'pazienti.csv';
file_triage = 'triage.csv';

%Gestione Archivio Pazienti
allergieDB = '';
storicoDB = '';
paziente_esiste = false;

if exist(file_pazienti, 'file')
    opts = detectImportOptions(file_pazienti);
    opts.VariableTypes = {'char', 'char', 'double', 'char', 'char'};
    tabella_pazienti = readtable(file_pazienti, opts);
    
    riga = strcmp(tabella_pazienti.CodiceFiscale, cf_paziente);
    if any(riga)
        paziente_esiste = true;
        if iscell(tabella_pazienti.Allergie)
            allergieDB = char(tabella_pazienti.Allergie{riga});
            storicoDB = char(tabella_pazienti.StoricoClinico{riga});
        else
            allergieDB = char(tabella_pazienti.Allergie(riga));
            storicoDB = char(tabella_pazienti.StoricoClinico(riga));
        end
    end
else
    tabella_pazienti = table([], [], [], [], [], 'VariableNames', ...
        {'CodiceFiscale', 'NomeCognome', 'Eta', 'Allergie', 'StoricoClinico'});
end

if ~paziente_esiste
    nuovo_paziente = table({cf_paziente}, {'Paziente Anonimo'}, eta, {''}, {''}, ...
        'VariableNames', {'CodiceFiscale', 'NomeCognome', 'Eta', 'Allergie', 'StoricoClinico'});
    tabella_pazienti = [tabella_pazienti; nuovo_paziente];
    writetable(tabella_pazienti, file_pazienti);
end

%Calcolo Triage 
[codiceColore, indicazioni] = valutaTriage(sat, fc, pa, sintomi_correnti, allergieDB, storicoDB);
app.IndicazionidelsistemaTextArea.Value = indicazioni;

%Aggiornamento Storico 
data_ora_corrente = char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
dati_tabella = {};

if exist(file_triage, 'file')
    opts_t = detectImportOptions(file_triage);
    opts_t.VariableTypes = {'char', 'double', 'double', 'double', 'char', 'char', 'char'};
    tabella_triage = readtable(file_triage, opts_t);
    
    filtro = strcmp(tabella_triage.FK_Paziente, cf_paziente);
    if any(filtro)
        cronologia = tabella_triage(filtro, :);
        cronologia = sortrows(cronologia, 'DataOra', 'descend');
        for i = 1:height(cronologia)
            d_ora = cronologia.DataOra{i};
            c_cod = cronologia.CodiceAssegnato{i};
            s_sint = cronologia.Sintomi{i};
            dati_tabella = [dati_tabella; {char(d_ora), char(c_cod), char(s_sint)}];
        end
    end
else
    tabella_triage = table([], [], [], [], [], [], [], 'VariableNames', ...
        {'FK_Paziente', 'FrequenzaCardiaca', 'PressioneSistolica', 'SaturazioneOssigeno', 'Sintomi', 'CodiceAssegnato', 'DataOra'});
end

app.StoricoTable.Data = dati_tabella;

%Lampadina e allarme 
switch codiceColore
    case 'Rosso'
        app.CODICELamp.Color = [1 0 0];   
        fs = 8000; t = 0:1/fs:0.25;
        suono = [sin(2*pi*880*t), sin(2*pi*440*t), sin(2*pi*880*t)];
        sound(suono, fs);
    case 'Giallo'
        app.CODICELamp.Color = [1 0.8 0]; 
    case 'Verde'
        app.CODICELamp.Color = [0 1 0];   
    otherwise
        app.CODICELamp.Color = [0.5 0.5 0.5]; 
end

%Salvataggio del Triage nel database 
nuovo_triage = table({cf_paziente}, fc, pa, sat, {indicazioni}, {codiceColore}, {data_ora_corrente}, ...
    'VariableNames', {'FK_Paziente', 'FrequenzaCardiaca', 'PressioneSistolica', 'SaturazioneOssigeno', 'Sintomi', 'CodiceAssegnato', 'DataOra'});

tabella_triage = [tabella_triage; nuovo_triage];
writetable(tabella_triage, file_triage);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CODICEFISCALEEditFieldLabel = uilabel(app.UIFigure);
            app.CODICEFISCALEEditFieldLabel.Text = 'CODICE FISCALE ';
            app.CODICEFISCALEEditField = uieditfield(app.UIFigure, 'text');
            app.NOMEECOGNOMEEditFieldLabel = uilabel(app.UIFigure);
            app.NOMEECOGNOMEEditFieldLabel.Text = 'NOME E COGNOME';
            app.NOMEECOGNOMEEditField = uieditfield(app.UIFigure, 'text');
            app.ETEditFieldLabel = uilabel(app.UIFigure);
            app.ETEditFieldLabel.Text = 'ETÀ';
            app.ETEditField = uieditfield(app.UIFigure, 'numeric');
            app.FCbpmEditFieldLabel = uilabel(app.UIFigure);
            app.FCbpmEditFieldLabel.Text = 'FC (bpm)';
            app.FCbpmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PAmmHgEditFieldLabel = uilabel(app.UIFigure);
            app.PAmmHgEditFieldLabel.Text = 'PA (mmHg)';
            app.PAmmHgEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Sintomi e note mediche';
            app.SintomienotemedicheTextArea = uitextarea(app.UIFigure);
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Indicazioni del sistema';
            app.IndicazionidelsistemaTextArea = uitextarea(app.UIFigure);
            app.CODICELampLabel = uilabel(app.UIFigure);
            app.CODICELampLabel.Text = 'CODICE';
            app.CODICELamp = uilamp(app.UIFigure);
            app.AVVIAButton = uibutton(app.UIFigure, 'push');
            app.AVVIAButton.ButtonPushedFcn = createCallbackFcn(app, @AVVIAButtonPushed, true);
            app.AVVIAButton.Text = 'AVVIA';
            app.StoricoTable = uitable(app.UIFigure);
            app.StoricoTable.ColumnName = {'Data e ora'; 'Codice'; 'Sintomi Passati'; ''};
            app.StoricoTable.RowName = {};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'SATURAZIONE O2';
            app.SATURAZIONEO2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicazionebottone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end